classdef LOPER_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RegionLabel                  matlab.ui.control.Label
        EditField_8                  matlab.ui.control.EditField
        ExportReNuPrtomatfileButton  matlab.ui.control.Button
        sLabel_2                     matlab.ui.control.Label
        SteadyStateafterLabel        matlab.ui.control.Label
        TemperatureTreshholdLabel    matlab.ui.control.Label
        EditField_7                  matlab.ui.control.NumericEditField
        Spinner                      matlab.ui.control.Spinner
        PlotModeButtonGroup          matlab.ui.container.ButtonGroup
        ColormapButton               matlab.ui.control.ToggleButton
        SeperateButton               matlab.ui.control.ToggleButton
        StackedButton                matlab.ui.control.ToggleButton
        FileLabel                    matlab.ui.control.Label
        EditField_6                  matlab.ui.control.EditField
        CreateFigureofTabButton      matlab.ui.control.Button
        FormatFileButton             matlab.ui.control.Button
        txttocsvButton               matlab.ui.control.Button
        SplitButton                  matlab.ui.control.Button
        sLabel                       matlab.ui.control.Label
        DurationLabel                matlab.ui.control.Label
        EditField_4                  matlab.ui.control.NumericEditField
        OpenFileButton               matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        TemperaturesTab              matlab.ui.container.Tab
        HeatDataTab                  matlab.ui.container.Tab
        SystemDataTab                matlab.ui.container.Tab
        TimeDataTab                  matlab.ui.container.Tab
        frackJkgLabel_2              matlab.ui.control.Label
        EnthalpychangeLabel          matlab.ui.control.Label
        EditField_5                  matlab.ui.control.NumericEditField
        WLabel                       matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        frackJkgLabel                matlab.ui.control.Label
        fraclminLabel                matlab.ui.control.Label
        HeaterPowerLabel             matlab.ui.control.Label
        EnthalpyinLabel              matlab.ui.control.Label
        MassflowLabel                matlab.ui.control.Label
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        TimeSlider                   matlab.ui.control.Slider
        TimeSliderLabel              matlab.ui.control.Label
        EnergyTab                    matlab.ui.container.Tab
        IOTab                        matlab.ui.container.Tab
        VapourFractionTab            matlab.ui.container.Tab
        HTCTab                       matlab.ui.container.Tab
        HTCSimulatedTab              matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        %% variables
        rawData;
        Q_dot;
        temperature_wall;
temperature_wall_outside;
        IO_Enthalpy;
        MassFlow;
        HeaterPower;
        res_Heating;
        HTC;
        HTCTiles;
        Efficiency;
        IO_Efficiency;
        IO_HTC;
        IO_HTC_calc;
        IO_Pressure;
        temperature_fluid;
        Enthalpy;
        VapourFrac;
        HTC_sim;
        pos_TC_abs;
        res_Heating_flux;
        Q_loss;
        File;
        Nu_exp;
        Re_exp;
        Pr_exp;

        %% tiles
        TimeTabTiles;
        TTTax1;
        TTTax2;
        TTTax3;
        HeatChangeTiles;
        TemperatureTiles;
        SystemDataTiles;
        EnergyBalTiles
        EfficiencyTiles;
        VapourFracTiles;
        HTCTiles_sim;

    end
    methods (Access = private)

        %% Plots Time Graphs
        function plot_TimeGraphs(app,time_index)

            app.EditField.Value=app.rawData.DurchflussInL_min(time_index); %Durchfluss
            app.EditField_2.Value=app.rawData.EintrittsenthalpieInKJ_kg(time_index); %Eintrittsenthalpie
            app.EditField_3.Value=app.rawData.VerdampferleistungSollInW(time_index); %Verdampferleistung
            app.EditField_5.Value=app.IO_Enthalpy(time_index,2)-app.IO_Enthalpy(time_index,1); %Enthalpieänderung

            plot(app.TTTax1,app.temperature_wall(time_index,:),app.pos_TC_abs,"-o"); hold(app.TTTax1,'on');
            plot(app.TTTax1,app.temperature_wall_outside(time_index,:),app.pos_TC_abs,"-o");
            plot(app.TTTax1,app.temperature_fluid(time_index,:),app.pos_TC_abs,"-o");hold(app.TTTax1,'off');
            legend(app.TTTax1,'T_{pipe}','T_{pipe outside}','T_{fluid}');
            ylabel(app.TTTax1,'Position');
            xlabel(app.TTTax1,'°C')
            title(app.TTTax1,'Temperature');
            xlim(app.TTTax1,'padded')
            ylim(app.TTTax1,'tight')
            grid(app.TTTax1);


            plot(app.TTTax2,app.res_Heating_flux(time_index,:),app.pos_TC_abs,"-o");
            ylabel(app.TTTax2,'Position');
            xlabel(app.TTTax2,'$$\frac{W}{m^{2}}$$',Interpreter='latex')
            title(app.TTTax2,'Heat Flux');
            xlim(app.TTTax2,'padded')
            ylim(app.TTTax2,'tight')
            grid(app.TTTax2);


            plot(app.TTTax3,app.HTC(time_index,:),app.pos_TC_abs,"-o"); hold(app.TTTax3,'on');
            plot(app.TTTax3,app.HTC_sim(time_index,:),app.pos_TC_abs,"-o");
            legend(app.TTTax3,'HTC_{meas}','HTC_{calc}');
            xlabel(app.TTTax3,'$$\frac{W}{m^{2}K}$$',Interpreter='latex');
            ylabel(app.TTTax3,'Position');
            xlim(app.TTTax3,'padded')
            ylim(app.TTTax3,'tight')
            title(app.TTTax3,'Heat Transfer Coefficient');
            grid(app.TTTax3);
            hold(app.TTTax3,'off')
        end

        %% Plots general System Data
        function plot_SystemData(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';

            ax1 = nexttile(Tile);
            plot(ax1,app.rawData.deltaTInS,app.rawData.DurchflussInL_min);
            title(ax1,'Durchfluss');
            ylabel(ax1,'L/min')
            ylim(ax1,'padded')
            xlim(ax1,'tight')

            ax2 = nexttile(Tile);
            plot(ax2,app.rawData.deltaTInS,app.rawData.EintrittsenthalpieInKJ_kg,'DisplayName','Soll'); hold(ax2,'on');
            plot(ax2,app.rawData.deltaTInS,app.IO_Enthalpy(:,1),'DisplayName','Ist');
            title(ax2,'Eintrittsenthalpie');
            legend(ax2);
            ylabel(ax2,'kJ/kg')
            ylim(ax2,'padded')
            xlim(ax2,'tight')
            hold(ax2,'off');

            ax3 = nexttile(Tile);
            plot(ax3,app.rawData.deltaTInS,app.IO_Pressure(:,1),'DisplayName','Pre');hold(ax3,'on');
            plot(ax3,app.rawData.deltaTInS,app.IO_Pressure(:,2),'DisplayName','Post')
            legend(ax3);
            title(ax3,'Pressure');
            ylabel(ax3,'bar')
            ylim(ax3,'padded')
            xlim(ax3,'tight')
            hold(ax3,'off');

            ax4 = nexttile(Tile);
            plot(ax4,app.rawData.deltaTInS,app.rawData.VerdampferleistungSollInW,'DisplayName','Soll'); hold(ax4,'on');
            plot(ax4,app.rawData.deltaTInS,app.rawData.GesamtstromInA.*app.rawData.SpannungsabfallKRInV,'DisplayName','Ist');
            legend(ax4);
            title(ax4,'Verdampferleistung');
            ylabel(ax4,'W')
            ylim(ax4,'padded')
            xlim(ax4,'tight')
            hold(ax4,'off');
        end

        %% Plots temperatures (stacked)
        function plot_TemperatureData(app,Tile,mode)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';

            switch mode

                case 1
                    ax1=nexttile(Tile);
                    plot(ax1,app.rawData.deltaTInS,flip(app.temperature_wall,2));
                    xlabel(ax1,'Seconds');
                    title(ax1,'Temperature_{wall}');
                    ylim(ax1,'padded')
                    xlim(ax1,'tight')
                    ylabel(ax1,'°C','rotation',0,'FontSize',12)
                    legend(ax1,'T_{100}','T_{90}','T_{80}','T_{70}','T_{60}','T_{50}','T_{40}','T_{30}','T_{20}','T_{10}','T_{0}');
                    grid(ax1);
                    hold(ax1,'off');

                case 2
                    title(Tile,'Temperature')
                    xlabel(Tile,'s')
                    ylabel(Tile,'°C')
                    % Tile 1
                    ax1 = nexttile(Tile);
                    plot(ax1,app.rawData.deltaTInS,app.rawData.RohrinnentemperaturKR0In_C,'DisplayName','RohrinnentemperaturKR0');
                    ylim(ax1,'padded')
                    % Tile 2
                    ax2 = nexttile(Tile);
                    plot(ax2,app.rawData.deltaTInS,app.rawData.RohrinnentemperaturKR2In_C,'DisplayName','RohrinnentemperaturKR2');
                    ylim(ax2,'padded')
                    % Tile 3
                    ax3 = nexttile(Tile);
                    plot(ax3,app.rawData.deltaTInS,app.rawData.RohrinnentemperaturKR3In_C,'DisplayName','RohrinnentemperaturKR3');
                    ylim(ax3,'padded')
                    % Tile 4
                    ax4 = nexttile(Tile);
                    plot(ax4,app.rawData.deltaTInS,app.rawData.RohrinnentemperaturKR4In_C,'DisplayName','RohrinnentemperaturKR4');
                    ylim(ax4,'padded')
                    % Tile 5
                    ax5 = nexttile(Tile);
                    plot(ax5,app.rawData.deltaTInS,app.rawData.RohrinnentemperaturKR5In_C,'DisplayName','RohrinnentemperaturKR5');
                    ylim(ax5,'padded')
                    % Tile 6
                    ax6 = nexttile(Tile);
                    plot(ax6,app.rawData.deltaTInS,app.rawData.RohrinnentemperaturKR6In_C,'DisplayName','RohrinnentemperaturKR6');
                    ylim(ax6,'padded')
                    % Tile 7
                    ax7 = nexttile(Tile);
                    plot(ax7,app.rawData.deltaTInS,app.rawData.RohrinnentemperaturKR7In_C,'DisplayName','RohrinnentemperaturKR7');
                    ylim(ax7,'padded')
                    % Tile 8
                    ax8 = nexttile(Tile);
                    plot(ax8,app.rawData.deltaTInS,app.rawData.RohrinnentemperaturKR8In_C,'DisplayName','RohrinnentemperaturKR8');
                    ylim(ax8,'padded')
                    % Tile 8
                    ax9 = nexttile(Tile);
                    plot(ax9,app.rawData.deltaTInS,app.rawData.RohrinnentemperaturKR9In_C,'DisplayName','RohrinnentemperaturKR9');
                    ylim(ax9,'padded')
                    % Tile 8
                    ax10 = nexttile(Tile);
                    plot(ax10,app.rawData.deltaTInS,app.rawData.RohrinnentemperaturKR10In_C,'DisplayName','RohrinnentemperaturKR10');
                    ylim(ax10,'padded')

                case 3
                    ax1=nexttile(Tile);
                    h=pcolor(ax1,app.rawData.deltaTInS,[0 93.1500  243.1500  392.9500  568.1500  693.4500  743.4000  793.0000  843.3000  893.7500],app.temperature_wall');
                    set(h, 'EdgeColor', 'none');
                    title(ax1,'Temperature_{wall}');
                    c=colorbar(ax1);
                    ylabel(c,'\circC','rotation',0,'FontSize',12)
                    set(ax1,'YDir','normal')
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'h in cm')
            end
        end

        %% Plots Energy Balance
        function plot_Energy(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';
            ax1=nexttile(Tile);
            plot(ax1,app.rawData.deltaTInS,app.HeaterPower,'DisplayName','Heater Power');
            hold(ax1,'on')
            plot(ax1,app.rawData.deltaTInS,diff(app.IO_Enthalpy,1,2).*app.MassFlow*1000,'DisplayName','Enthalpy Change');
            title(ax1,'Energy Balance');
            xlabel(ax1,'Seconds');
            ylabel(ax1,'kW')
            ylim(ax1,'padded')
            xlim(ax1,'tight')
            grid(ax1,'on')
            legend(ax1)
            hold(ax1,'off');
        end

        %% Plots Heat Transfer Coefficient
        function plot_HTC(app,Tile,mode)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';
            switch mode
                case 1

                    ax1=nexttile(Tile);
                    plot(ax1,app.rawData.deltaTInS,flip(app.HTC,2)/1000);
                    title (ax1,'Heat Transfer Coefficient');
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'$$\frac{kW}{m^{2}K}$$',Interpreter='latex',Rotation=0,FontSize=12)
                    ylim(ax1,'padded')
                    xlim(ax1,'tight')
                    legend(ax1,'HTC_{910}','HTC_{89}','HTC_{78}','HTC_{67}','HTC_{56}','HTC_{45}','HTC_{34}','HTC_{23}','HTC_{12}','HTC_{01}');
                    grid(ax1);
                case 3
                    ax1=nexttile(Tile);
                    h=pcolor(ax1,app.rawData.deltaTInS,[0 93.1500  243.1500  392.9500  568.1500  693.4500  743.4000  793.0000  843.3000  893.7500],app.HTC'/1000);
                    set(h, 'EdgeColor', 'none');
                    c=colorbar(ax1);
                    ylabel(c,'$$\frac{kW}{m^{2}K}$$',Interpreter='latex',Rotation=0,FontSize=12)
                    title(ax1,'Heat Transfer Coefficient')
                    set(ax1,'YDir','normal')
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'h in cm')
            end

        end

        %% Plots Heat Transfer Coefficient
                function plot_HTC_sim(app,Tile,mode)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';
            switch mode
                case 1

                    ax1=nexttile(Tile);
                    plot(ax1,app.rawData.deltaTInS,flip(app.HTC_sim,2));
                    title (ax1,'Heat Transfer Coefficient');
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'$$\frac{W}{m^{2}K}$$',Interpreter='latex')
                    ylim(ax1,'padded')
                    xlim(ax1,'tight')
                    legend(ax1,'HTC_{910}','HTC_{89}','HTC_{78}','HTC_{67}','HTC_{56}','HTC_{45}','HTC_{34}','HTC_{23}','HTC_{12}','HTC_{01}');
                    grid(ax1);
                case 3
                    ax1=nexttile(Tile);
                    h=pcolor(ax1,app.rawData.deltaTInS,[0 93.1500  243.1500  392.9500  568.1500  693.4500  743.4000  793.0000  843.3000  893.7500],app.HTC_sim');
                    set(h, 'EdgeColor', 'none');
                    c=colorbar(ax1);
                    ylabel(c,'$$\frac{W}{m^{2}K}$$',Interpreter='latex',Rotation=0,FontSize=12)
                    title(ax1,'Heat Transfer Coefficient')
                    set(ax1,'YDir','normal')
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'h in cm')
            end

        end

        %% Plots IO Efficiency and HTC
        function plot_HTC_IO(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';
            ax1=nexttile(Tile);
            plot(ax1,app.rawData.deltaTInS,app.Q_loss); 
            title (ax1,'Heat Loss of the system');
            xlabel(ax1,'Seconds');
            ylabel(ax1,'W')
            ylim(ax1,'padded')
            xlim(ax1,'tight')
            grid(ax1,'on');
            hold(ax1,'off');
        end

        %% Plots Enthalpy at each Section
        function plot_Enthalpy(app,Tile, mode)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';
            switch mode
                case 1
                    ax1=nexttile(Tile);
                    plot(ax1,app.rawData.deltaTInS,flip(app.Enthalpy,2));
                    title(ax1,'Enthalpy')
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'$$\frac{kJ}{kg}$$',Interpreter='latex',Rotation=0,FontSize=12)
                    ylim(ax1,'padded')
                    xlim(ax1,'tight')
                    legend(ax1,'h_{100}','h_{90}','h_{80}','h_{70}','h_{60}','h_{50}','h_{40}','h_{30}','h_{20}','h_{10}','h_{0}');
                    grid(ax1);
                case 3
                    ax1=nexttile(Tile);
                    h=pcolor(ax1,app.rawData.deltaTInS,[0 93.1500  243.1500  392.9500  568.1500  693.4500  743.4000  793.0000  843.3000  893.7500],app.Enthalpy');
                    set(h, 'EdgeColor', 'none');
                    c=colorbar(ax1);
                    ylabel(c,'$$\frac{kJ}{kg}$$',Interpreter='latex',Rotation=0,FontSize=12)
                    title(ax1,'Enthalpy')
                    set(ax1,'YDir','normal')
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'h in cm')
            end

        end

        %% Plots Vapour Fraction at each Section
        function plot_VapourFrac(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';
            ax1=nexttile(Tile);
            h=pcolor(ax1,app.rawData.deltaTInS,[0 93.1500  243.1500  392.9500  568.1500  693.4500  743.4000  793.0000  843.3000  893.7500],app.VapourFrac');
            set(h, 'EdgeColor', 'none');
            c=colorbar(ax1);
            ylabel(c,'$\dot{x}$', 'Interpreter','latex',Rotation=0,FontSize=12)
            title(ax1,'Vapour Fraction');
            set(ax1,'YDir','normal');
            xlabel(ax1,'Seconds');
            ylabel(ax1,'h in cm');

        end

        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.TemperatureTiles = tiledlayout(app.TemperaturesTab,1,1);
            app.HeatChangeTiles= tiledlayout(app.HeatDataTab,1,1);
            app.SystemDataTiles = tiledlayout(app.SystemDataTab,4,1);
            app.TimeTabTiles = tiledlayout(app.Panel,1,3);
            app.TimeTabTiles.TileSpacing = 'compact';
            app.TimeTabTiles.Padding = 'compact';
            app.EnergyBalTiles = tiledlayout(app.EnergyTab,1,1);
            app.EfficiencyTiles = tiledlayout(app.IOTab,1,1);
            app.VapourFracTiles = tiledlayout(app.VapourFractionTab,1,1);
            app.HTCTiles = tiledlayout(app.HTCTab,1,1);
            app.HTCTiles_sim = tiledlayout(app.HTCSimulatedTab,1,1);

        end

        % Button pushed function: OpenFileButton
        function LeftPanelButtonDown(app, event)

            app.OpenFileButton.Text = '';
app.OpenFileButton.Icon = '332-loader-3.gif';

            script;

                        app.OpenFileButton.Icon = '';
            % Change button name
            app.OpenFileButton.Text = 'Open File';
close all;
 if ~exist('rawData','var')
     return
 end

            app.TemperatureTiles = tiledlayout(app.TemperaturesTab,1,1);
            app.HeatChangeTiles= tiledlayout(app.HeatDataTab,1,1);
            app.SystemDataTiles = tiledlayout(app.SystemDataTab,4,1);
            app.TimeTabTiles = tiledlayout(app.Panel,1,3);
            app.TimeTabTiles.TileSpacing = 'compact';
            app.TimeTabTiles.Padding = 'compact';
            app.EnergyBalTiles = tiledlayout(app.EnergyTab,1,1);
            app.EfficiencyTiles = tiledlayout(app.IOTab,1,1);
            app.VapourFracTiles = tiledlayout(app.VapourFractionTab,1,1);
            app.HTCTiles = tiledlayout(app.HTCTab,1,1);
            app.HTCTiles_sim = tiledlayout(app.HTCSimulatedTab,1,1);

            app.rawData=rawData;
            app.temperature_wall=temperature_wall;
            app.IO_Enthalpy=IO_Enthalpy;
            app.MassFlow=MassFlow;
            app.HeaterPower=HeaterPower;
            app.res_Heating=res_Heating;
            app.HTC=HTC;
            app.IO_Pressure=Pressure;
            app.temperature_fluid=temperature_fluid;
            app.Enthalpy=Enthalpy;
            app.VapourFrac=VapourFrac;
            app.HTC_sim=HTC_sim;
            app.pos_TC_abs=pos_TC_abs;
            app.res_Heating_flux=res_Heating_flux;
            app.Q_loss=Q_loss;
            app.temperature_wall_outside=temperature_wall_outside;
            app.File=File(1:end-4);
            app.Re_exp=Re_exp;
            app.Nu_exp=Nu_exp;
            app.Pr_exp=Pr_exp;

            app.EditField_6.Value=app.File; % Current file
            app.EditField_4.Value=totalTime; %Dauer

            if any(app.VapourFrac>0,'all')
                app.EditField_8.Value='2 Phase';
            else
                app.EditField_8.Value='1 Phase';
            end

            plot_TemperatureData(app,app.TemperatureTiles,1);
            %plot_HeatChangeData(app,app.HeatChangeTiles,0);
            plot_Enthalpy(app,app.HeatChangeTiles,1);
            plot_SystemData(app,app.SystemDataTiles);
            plot_Energy(app,app.EnergyBalTiles);
            plot_HTC(app,app.HTCTiles,1);
            plot_HTC_IO(app,app.EfficiencyTiles)
            plot_VapourFrac(app,app.VapourFracTiles);
            plot_HTC_sim(app,app.HTCTiles_sim,1);

            app.TimeSlider.Limits = [0 totalTime];
            app.TTTax1=nexttile(app.TimeTabTiles);
            app.TTTax2=nexttile(app.TimeTabTiles);
            app.TTTax3=nexttile(app.TimeTabTiles);
            plot_TimeGraphs(app,1);

            SpinnerValueChanged(app, event);
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            time_index = floor(app.TimeSlider.Value)*10+1;
            plot_TimeGraphs(app,time_index);
        end

        % Button pushed function: SplitButton
        function SplitButtonPushed(app, event)
            %% Splits multiple runs into seperate files
            splitIntoSingleRuns;
        end

        % Button pushed function: FormatFileButton
        function FormatFileButtonPushed(app, event)
            %% Replaces , (comma) with . (dot) and   (tab) with  ;  (semicolon) in Decimals in the CSV file
            reformatFile;
        end

        % Button pushed function: txttocsvButton
        function txttocsvButtonPushed(app, event)
            %% Splits converts TXT file into CSV and replaces , (comma) with . (dot) and
            %% split into singele runs
            convertTXTtoCSV;
        end

        % Button pushed function: CreateFigureofTabButton
        function CreateFigureofTabButtonPushed(app, event)
            selectedButton = app.PlotModeButtonGroup.SelectedObject.Text;

            switch selectedButton
                case 'Stacked'
                    mode=1;
                case 'Seperate'
                    mode=2;
                case 'Colormap'
                    mode=3;
            end

            if (isprop(app, 'rawData') && ~isempty(app.rawData))
                switch app.TabGroup.SelectedTab.Title

                    case 'Temperatures'
                        fig=figure;
                        TemperatureTiles = tiledlayout(fig,1,1);
                        plot_TemperatureData(app,TemperatureTiles,mode);

                    case 'Heat Data'
                        fig=figure;
                        HeatChangeTiles = tiledlayout(fig,1,1);
                        %plot_HeatChangeData(app,HeatChangeTiles,0);
                        plot_Enthalpy(app,HeatChangeTiles,mode);

                    case 'System Data'
                        fig=figure;
                        SystemDataTiles = tiledlayout(fig,4,1);
                        plot_SystemData(app,SystemDataTiles);

                    case 'Time Data'
                        msgbox('Figure creation not supported for this tab');

                    case 'Energy'
                        fig=figure;
                        EnergyBalTiles = tiledlayout(fig,1,1);
                        plot_Energy(app,EnergyBalTiles);

                    case 'IO'
                        fig=figure;
                        EfficiencyTiles = tiledlayout(fig,1,1);
                        plot_HTC_IO(app,EfficiencyTiles);

                end
            end
        end

        % Selection changed function: PlotModeButtonGroup
        function PlotModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotModeButtonGroup.SelectedObject.Text;
            if (isprop(app, 'rawData') && ~isempty(app.rawData))
                HTCTiles = tiledlayout(app.HTCTab,1,1);
                switch selectedButton
                    case 'Stacked'
                        TemperatureTiles = tiledlayout(app.TemperaturesTab,1,1);
                        HeatChangeTiles = tiledlayout(app.HeatDataTab,1,1);
                        HTCTiles_sim = tiledlayout(app.HTCSimulatedTab,1,1);
                        mode=1;
                    case 'Seperate'
                        TemperatureTiles = tiledlayout(app.TemperaturesTab,10,1);
                        HeatChangeTiles = tiledlayout(app.HeatDataTab,1,1);
                        HTCTiles_sim = tiledlayout(app.HTCSimulatedTab,1,1);
                        mode=2;
                    case 'Colormap'
                        TemperatureTiles = tiledlayout(app.TemperaturesTab,1,1);
                        HeatChangeTiles = tiledlayout(app.HeatDataTab,1,1);
                        HTCTiles_sim = tiledlayout(app.HTCSimulatedTab,1,1);
                        mode=3;
                end

                plot_Enthalpy(app,HeatChangeTiles,mode);
                plot_TemperatureData(app,TemperatureTiles,mode);
                plot_HTC(app,HTCTiles,mode);
                plot_HTC_sim(app,HTCTiles_sim,mode)
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            if (isprop(app, 'rawData') && ~isempty(app.rawData))
                limit = app.Spinner.Value;
                tempdiff=diff(app.temperature_wall);

                for i=1:length(tempdiff)
                    rem_sum=sum(tempdiff(i:end,:));
                    if max(abs(rem_sum))<limit
                        break;
                    end
                end

                if i<0.9*length(app.temperature_wall) && (max(range(app.temperature_wall))<=2*limit)

                    app.EditField_7.Value=i/10;
                else
                    app.EditField_7.Value=Inf;
                end

            end
        end

        % Button pushed function: ExportReNuPrtomatfileButton
        function ExportReNuPrtomatfileButtonPushed(app, event)

if not(isfolder('matFolder'))
    mkdir('matFolder')
end

            if (isprop(app, 'rawData') && ~isempty(app.rawData))
                name="matFolder/"+ app.File+".mat";
                Nu_exp=app.Nu_exp;
                Re_exp=app.Re_exp;
                Pr_exp=app.Pr_exp;
                save(name,'Nu_exp','Re_exp','Pr_exp');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1053, 1053};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {112, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OpenFileButton = uibutton(app.LeftPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @LeftPanelButtonDown, true);
            app.OpenFileButton.Text = 'Open File';
            app.EditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_4.Editable = 'off';
            app.DurationLabel = uilabel(app.LeftPanel);
            app.DurationLabel.Text = 'Duration';
            app.sLabel = uilabel(app.LeftPanel);
            app.sLabel.Text = 's';
            app.SplitButton = uibutton(app.LeftPanel, 'push');
            app.SplitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitButton.Text = 'Split';
            app.txttocsvButton = uibutton(app.LeftPanel, 'push');
            app.txttocsvButton.ButtonPushedFcn = createCallbackFcn(app, @txttocsvButtonPushed, true);
            app.txttocsvButton.Text = '.txt to .csv';
            app.FormatFileButton = uibutton(app.LeftPanel, 'push');
            app.FormatFileButton.ButtonPushedFcn = createCallbackFcn(app, @FormatFileButtonPushed, true);
            app.FormatFileButton.Text = 'Format File';
            app.CreateFigureofTabButton = uibutton(app.LeftPanel, 'push');
            app.CreateFigureofTabButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFigureofTabButtonPushed, true);
            app.CreateFigureofTabButton.Text = {'Create Figure '; 'of Tab'};
            app.EditField_6 = uieditfield(app.LeftPanel, 'text');
            app.EditField_6.Editable = 'off';
            app.FileLabel = uilabel(app.LeftPanel);
            app.FileLabel.Text = 'File';
            app.PlotModeButtonGroup = uibuttongroup(app.LeftPanel);
            app.PlotModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotModeButtonGroupSelectionChanged, true);
            app.PlotModeButtonGroup.Title = 'Plot Mode';
            app.StackedButton = uitogglebutton(app.PlotModeButtonGroup);
            app.StackedButton.Text = 'Stacked';
            app.StackedButton.Value = true;
            app.SeperateButton = uitogglebutton(app.PlotModeButtonGroup);
            app.SeperateButton.Text = 'Seperate';
            app.ColormapButton = uitogglebutton(app.PlotModeButtonGroup);
            app.ColormapButton.Text = 'Colormap';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.Step = 0.5;
            app.Spinner.Limits = [0 Inf];
            app.Spinner.ValueDisplayFormat = '%.1f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Tooltip = {'Maximum Temperature Change over Time for Steady State Detection'};
            app.Spinner.Value = 1;
            app.EditField_7 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Tooltip = {'If Steady State occurs within the last 10% of the measurements it is disregarded'};
            app.TemperatureTreshholdLabel = uilabel(app.LeftPanel);
            app.TemperatureTreshholdLabel.Tooltip = {'Maximum Temperature Change over Time for Steady State Detection'};
            app.TemperatureTreshholdLabel.Text = {'Temperature'; 'Treshhold'};
            app.SteadyStateafterLabel = uilabel(app.LeftPanel);
            app.SteadyStateafterLabel.Tooltip = {'If Steady State occurs within the last 10% of the measurements it is disregarded'};
            app.SteadyStateafterLabel.Text = {'Steady State '; 'after'};
            app.sLabel_2 = uilabel(app.LeftPanel);
            app.sLabel_2.Tooltip = {'Maximum Temperature Change over Time for Steady State Detection'};
            app.sLabel_2.Text = 's';
            app.ExportReNuPrtomatfileButton = uibutton(app.LeftPanel, 'push');
            app.ExportReNuPrtomatfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportReNuPrtomatfileButtonPushed, true);
            app.ExportReNuPrtomatfileButton.Text = {'Export Re, Nu, '; 'Pr to .mat file'};
            app.EditField_8 = uieditfield(app.LeftPanel, 'text');
            app.EditField_8.Editable = 'off';
            app.RegionLabel = uilabel(app.LeftPanel);
            app.RegionLabel.Text = 'Region';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TemperaturesTab = uitab(app.TabGroup);
            app.TemperaturesTab.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.TemperaturesTab.Title = 'Temperatures';
            app.HeatDataTab = uitab(app.TabGroup);
            app.HeatDataTab.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.HeatDataTab.Title = 'Heat Data';
            app.SystemDataTab = uitab(app.TabGroup);
            app.SystemDataTab.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SystemDataTab.Title = 'System Data';
            app.TimeDataTab = uitab(app.TabGroup);
            app.TimeDataTab.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.TimeDataTab.Title = 'Time Data';
            app.TimeSliderLabel = uilabel(app.TimeDataTab);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.TimeDataTab);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.EditField = uieditfield(app.TimeDataTab, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.TimeDataTab, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.TimeDataTab, 'numeric');
            app.EditField_3.Editable = 'off';
            app.MassflowLabel = uilabel(app.TimeDataTab);
            app.MassflowLabel.Text = 'Massflow';
            app.EnthalpyinLabel = uilabel(app.TimeDataTab);
            app.EnthalpyinLabel.Text = 'Enthalpy in';
            app.HeaterPowerLabel = uilabel(app.TimeDataTab);
            app.HeaterPowerLabel.Text = 'Heater Power';
            app.fraclminLabel = uilabel(app.TimeDataTab);
            app.fraclminLabel.Interpreter = 'latex';
            app.fraclminLabel.Text = '\(\frac{l}{min}\)';
            app.frackJkgLabel = uilabel(app.TimeDataTab);
            app.frackJkgLabel.Interpreter = 'latex';
            app.frackJkgLabel.Text = '\(\frac{kJ}{kg}\)';
            app.Panel = uipanel(app.TimeDataTab);
            app.WLabel = uilabel(app.TimeDataTab);
            app.WLabel.Text = 'W';
            app.EditField_5 = uieditfield(app.TimeDataTab, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EnthalpychangeLabel = uilabel(app.TimeDataTab);
            app.EnthalpychangeLabel.Text = 'Enthalpy change';
            app.frackJkgLabel_2 = uilabel(app.TimeDataTab);
            app.frackJkgLabel_2.Interpreter = 'latex';
            app.frackJkgLabel_2.Text = '\(\frac{kJ}{kg}\)';
            app.EnergyTab = uitab(app.TabGroup);
            app.EnergyTab.Title = 'Energy';
            app.IOTab = uitab(app.TabGroup);
            app.IOTab.Tooltip = {''};
            app.IOTab.Title = 'IO';
            app.VapourFractionTab = uitab(app.TabGroup);
            app.VapourFractionTab.Title = 'Vapour Fraction';
            app.HTCTab = uitab(app.TabGroup);
            app.HTCTab.Title = 'HTC';
            app.HTCSimulatedTab = uitab(app.TabGroup);
            app.HTCSimulatedTab.Title = 'HTC Simulated';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LOPER_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end