classdef LOPER_Summary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WLabel_2                        matlab.ui.control.Label
        HeatLossEditField               matlab.ui.control.NumericEditField
        HeatLossEditFieldLabel          matlab.ui.control.Label
        frackgminLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        frackJkgLabel                   matlab.ui.control.Label
        EnthalpyinEditField             matlab.ui.control.NumericEditField
        EnthalpyinEditFieldLabel        matlab.ui.control.Label
        frackgsLabel                    matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        SteadyStatedetectedonEditField  matlab.ui.control.EditField
        SteadyStatedetectedonEditFieldLabel  matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        withpmLabel                     matlab.ui.control.Label
        ExportFigureButton              matlab.ui.control.Button
        AveragedoverEditField           matlab.ui.control.NumericEditField
        AveragedoverEditFieldLabel      matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        DurationEditField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        HeaterPowerEditField            matlab.ui.control.NumericEditField
        HeaterPowerEditFieldLabel       matlab.ui.control.Label
        MassflowEditField               matlab.ui.control.NumericEditField
        MassflowEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        callingApp % Description
    end

    methods (Access = public)

        function  dispValues(app,i,dur)
            app.UITable.RowName='numbered';
            Temp_fluid_mean=mean(app.callingApp.Temp_fluid(i:end,:))';
            res_Heating_mean=mean(app.callingApp.res_Heating_flux(i:end,:))';
            HTC_mean=mean(app.callingApp.HTC(i:end,:))';
            VapourFrac_mean=mean(app.callingApp.VapourFrac(i:end,:))';
            app.UITable.Data=flip(table(Temp_fluid_mean,res_Heating_mean./1000,HTC_mean./1000,VapourFrac_mean));

            app.HeaterPowerEditField.Value=mean(app.callingApp.HeaterPower(i:end));
            app.HeatLossEditField.Value=mean(app.callingApp.Q_loss(i:end));
            app.MassflowEditField.Value=mean(app.callingApp.MassFlow(i:end));
            app.EditField.Value=mean(app.callingApp.MassFlow(i:end))*60;
            app.DurationEditField.Value=dur;
            app.AveragedoverEditField.Value=app.callingApp.deltaT;
            app.EnthalpyinEditField.Value=mean(app.callingApp.Enthalpy_IO(i:end,1));
            app.SteadyStatedetectedonEditField.Value=app.callingApp.File;
            app.KEditField.Value=app.callingApp.Spinner.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, i, dur)
            app.UITable.ColumnName={'Temp (fluid)'; 'Heat Flux (kw/m2)'; 'HTC (kw/m2K)';'Vapour Fraction'};
            app.callingApp=caller;
            dispValues(app,i,dur);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)

        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            name=app.callingApp.File + "_sum.jpg";
            exportapp(app.UIFigure,name)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MassflowEditFieldLabel = uilabel(app.UIFigure);
            app.MassflowEditFieldLabel.Text = 'Massflow';
            app.MassflowEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassflowEditField.Editable = 'off';
            app.HeaterPowerEditFieldLabel = uilabel(app.UIFigure);
            app.HeaterPowerEditFieldLabel.Text = 'Heater Power';
            app.HeaterPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeaterPowerEditField.Editable = 'off';
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Temperature'; 'Heat Flux'; 'HTC'; 'Vapour Fraction'};
            app.UITable.RowName = {};
            app.AveragedoverEditFieldLabel = uilabel(app.UIFigure);
            app.AveragedoverEditFieldLabel.Text = 'Averaged over';
            app.AveragedoverEditField = uieditfield(app.UIFigure, 'numeric');
            app.AveragedoverEditField.Editable = 'off';
            app.ExportFigureButton = uibutton(app.UIFigure, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            app.withpmLabel = uilabel(app.UIFigure);
            app.withpmLabel.Interpreter = 'latex';
            app.withpmLabel.Text = 'with $\pm$';
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'latex';
            app.Label.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.Editable = 'off';
            app.SteadyStatedetectedonEditFieldLabel = uilabel(app.UIFigure);
            app.SteadyStatedetectedonEditFieldLabel.Text = 'Steady State detected on';
            app.SteadyStatedetectedonEditField = uieditfield(app.UIFigure, 'text');
            app.SteadyStatedetectedonEditField.Editable = 'off';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Interpreter = 'latex';
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Interpreter = 'latex';
            app.sLabel_2.Text = 's';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Interpreter = 'latex';
            app.WLabel.Text = 'W';
            app.frackgsLabel = uilabel(app.UIFigure);
            app.frackgsLabel.Interpreter = 'latex';
            app.frackgsLabel.Text = '$\frac{kg}{s}$';
            app.EnthalpyinEditFieldLabel = uilabel(app.UIFigure);
            app.EnthalpyinEditFieldLabel.Text = 'Enthalpy in';
            app.EnthalpyinEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnthalpyinEditField.Editable = 'off';
            app.frackJkgLabel = uilabel(app.UIFigure);
            app.frackJkgLabel.Interpreter = 'latex';
            app.frackJkgLabel.Text = '$\frac{kJ}{kg}$';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.frackgminLabel = uilabel(app.UIFigure);
            app.frackgminLabel.Interpreter = 'latex';
            app.frackgminLabel.Text = '$\frac{kg}{min}$';
            app.HeatLossEditFieldLabel = uilabel(app.UIFigure);
            app.HeatLossEditFieldLabel.Text = 'Heat Loss';
            app.HeatLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeatLossEditField.Editable = 'off';
            app.WLabel_2 = uilabel(app.UIFigure);
            app.WLabel_2.Interpreter = 'latex';
            app.WLabel_2.Text = 'W';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LOPER_Summary(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end