classdef summaryLOPER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AveragedoverEditField       matlab.ui.control.NumericEditField
        AveragedoverEditFieldLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        DurationEditField           matlab.ui.control.NumericEditField
        DurationEditFieldLabel      matlab.ui.control.Label
        HeaterPowerEditField        matlab.ui.control.NumericEditField
        HeaterPowerEditFieldLabel   matlab.ui.control.Label
        MassflowEditField           matlab.ui.control.NumericEditField
        MassflowEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.UITable.RowName='numbered';
            
            app.callingApp=caller;
            app.UITable.Data=flip(table(mean(caller.temperature_fluid)',mean(caller.res_Heating_flux)'./1000,mean(caller.HTC)'./1000));
            %table(mean(caller.temperature_fluid)',mean(caller.res_Heating_flux)',mean(caller.HTC)')

            app.HeaterPowerEditField.Value=mean(caller.HeaterPower);
            app.MassflowEditField.Value=mean(caller.MassFlow);
            app.DurationEditField.Value=caller.totalTime;
            app.AveragedoverEditField.Value=caller.deltaT;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MassflowEditFieldLabel = uilabel(app.UIFigure);
            app.MassflowEditFieldLabel.Text = 'Massflow';
            app.MassflowEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeaterPowerEditFieldLabel = uilabel(app.UIFigure);
            app.HeaterPowerEditFieldLabel.Text = 'Heater Power';
            app.HeaterPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Temperature'; 'Heat Flux'; 'HTC'};
            app.UITable.RowName = {};
            app.AveragedoverEditFieldLabel = uilabel(app.UIFigure);
            app.AveragedoverEditFieldLabel.Text = 'Averaged over';
            app.AveragedoverEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = summaryLOPER(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end